classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataProcessorUIFigure  matlab.ui.Figure
        FolderIcon             matlab.ui.control.Image
        FileDirectory          matlab.ui.control.EditField
        FileEditFieldLabel     matlab.ui.control.Label
        UILabel                matlab.ui.control.Label
        Logo                   matlab.ui.control.Image
        StartButton            matlab.ui.control.Button
        TaskDropDown           matlab.ui.control.DropDown
        TaskLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        filepath = '';
        storedata
    end

    methods (Access = private)

        function processData(app)
            if app.filepath ~= ""
                app.storedata = StoreData;
                app.storedata.line1 = readtable(app.filepath);
            end
        end
        
        function saveData(app)
            class(app.storedata)
            [file,path] = uiputfile('*.mat');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            save(fullfile(path,file),'app.storedata',"-v7.3","-nocompression");
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: FolderIcon
        function FolderIconImageClicked(app, event)
            [file,path] = uigetfile('*.csv');
            if isequal(file,0)
                disp('User selected Cancel');
                app.filepath = '';
                app.FileDirectory.Value = '';
            else
                disp(['User selected ', fullfile(path,file)]);
                app.filepath = fullfile(path,file);
                app.FileDirectory.Value = app.filepath;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            value = app.TaskDropDown.Value;
            switch value
                case 'Process Data'
                    app.processData();
                case 'Save Data'
                    app.saveData();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DataProcessorUIFigure = uifigure('Visible', 'off');
            app.DataProcessorUIFigure.Name = 'DataProcessor';
            app.TaskLabel = uilabel(app.DataProcessorUIFigure);
            app.TaskLabel.Text = 'Task:';
            app.TaskDropDown = uidropdown(app.DataProcessorUIFigure);
            app.TaskDropDown.Items = {'Select Task', 'Process Data', 'Save Data'};
            app.TaskDropDown.Value = 'Select Task';
            app.StartButton = uibutton(app.DataProcessorUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Logo = uiimage(app.DataProcessorUIFigure);
            app.Logo.ImageSource = fullfile(pathToMLAPP, 'logo.jpg');
            app.UILabel = uilabel(app.DataProcessorUIFigure);
            app.UILabel.Text = 'DataProcessor';
            app.FileEditFieldLabel = uilabel(app.DataProcessorUIFigure);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileDirectory = uieditfield(app.DataProcessorUIFigure, 'text');
            app.FileDirectory.Editable = 'off';
            app.FolderIcon = uiimage(app.DataProcessorUIFigure);
            app.FolderIcon.ImageClickedFcn = createCallbackFcn(app, @FolderIconImageClicked, true);
            app.FolderIcon.ImageSource = fullfile(pathToMLAPP, 'folder.png');
            % Show the figure after all components are created
            app.DataProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataProcessorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataProcessorUIFigure)
        end
    end
end